classdef robot_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InversekinematicsLabel        matlab.ui.control.Label
        DirectkinematicsLabel         matlab.ui.control.Label
        PosZEditField                 matlab.ui.control.NumericEditField
        PosZEditFieldLabel            matlab.ui.control.Label
        PosYEditField                 matlab.ui.control.NumericEditField
        PosYEditFieldLabel            matlab.ui.control.Label
        PosXEditField                 matlab.ui.control.NumericEditField
        PosXEditFieldLabel            matlab.ui.control.Label
        BrzinarobotaSlider            matlab.ui.control.Slider
        BrzinarobotaSliderLabel       matlab.ui.control.Label
        Zglob3Slider                  matlab.ui.control.Slider
        Zglob3SliderLabel             matlab.ui.control.Label
        Zglob2Slider                  matlab.ui.control.Slider
        Zglob2SliderLabel             matlab.ui.control.Label
        Zglob1Slider                  matlab.ui.control.Slider
        Zglob1SliderLabel             matlab.ui.control.Label
        NainradaButtonGroup           matlab.ui.container.ButtonGroup
        AutomatskiButton              matlab.ui.control.RadioButton
        RucniinversekinematicsButton  matlab.ui.control.RadioButton
        RucnidirectkinematicsButton   matlab.ui.control.RadioButton
        IskljucenoButton              matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        SimulinkModelName % Description
        SimulinkConstantBlockName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SimulinkConstantBlockName = "simulation_three_motors";
            app.BrzinarobotaSlider.Enable = false;
            app.PosXEditField.Enable = false;
            app.PosYEditField.Enable = false;
            app.PosZEditField.Enable = false;
            app.Zglob1Slider.Enable = false;
            app.Zglob2Slider.Enable = false;
            app.Zglob3Slider.Enable = false; 
            set_param('simulation_three_motors/nacin_rada','Value',mat2str(1))
            
        end

        % Callback function: Zglob1Slider, Zglob1Slider
        function Join1SliderValueChanged(app, event)
            kut1 = app.Zglob1Slider.Value;
            set_param('simulation_three_motors/Joint1_value','Value', mat2str(round(kut1,2)));
        end

        % Value changed function: Zglob2Slider
        function Zglob2SliderValueChanged(app, event)
            kut2 = app.Zglob2Slider.Value;
            set_param('simulation_three_motors/Joint2_value','Value', mat2str(round(kut2,2)));
        end

        % Value changed function: Zglob3Slider
        function Zglob3SliderValueChanged(app, event)
            kut3 = app.Zglob3Slider.Value;
            set_param('simulation_three_motors/Joint3_value','Value', mat2str(round(kut3,2)));
        end

        % Value changed function: BrzinarobotaSlider
        function BrzinarobotaSliderValueChanged(app, event)
            brzina = app.BrzinarobotaSlider.Value;
            set_param('simulation_three_motors/Kp','Gain', mat2str(round(brzina,2)));
            set_param('simulation_three_motors/Kp1','Gain', mat2str(round(brzina,2)));
            set_param('simulation_three_motors/Kp2','Gain', mat2str(round(brzina,2)));
        end

        % Value changed function: PosXEditField
        function PosXEditFieldValueChanged(app, event)
            posx = app.PosXEditField.Value;
            set_param('simulation_three_motors/PosX','Value', mat2str(posx))
        end

        % Value changed function: PosYEditField
        function PosYEditFieldValueChanged(app, event)
            posy = app.PosYEditField.Value;
            set_param('simulation_three_motors/PosY','Value', mat2str(posy))
        end

        % Value changed function: PosZEditField
        function PosZEditFieldValueChanged(app, event)
            posz = app.PosZEditField.Value;
            set_param('simulation_three_motors/PosZ','Value', mat2str(posz))
        end

        % Selection changed function: NainradaButtonGroup
        function NainradaButtonGroupSelectionChanged(app, event)
            selectedButton = app.NainradaButtonGroup.SelectedObject;
            if selectedButton == app.IskljucenoButton
                app.BrzinarobotaSlider.Enable = false;
                app.PosXEditField.Enable = false;
                app.PosYEditField.Enable = false;
                app.PosZEditField.Enable = false;
                app.Zglob1Slider.Enable = false;
                app.Zglob2Slider.Enable = false;
                app.Zglob3Slider.Enable = false;
                set_param('simulation_three_motors/nacin_rada','Value',mat2str(1))
                
            end
            if selectedButton == app.RucnidirectkinematicsButton
                app.Zglob1Slider.Enable = true;
                app.Zglob2Slider.Enable = true;
                app.Zglob3Slider.Enable = true; 
                app.BrzinarobotaSlider.Enable = true;
                app.PosXEditField.Enable = false;
                app.PosYEditField.Enable = false;
                app.PosZEditField.Enable = false;
                set_param('simulation_three_motors/nacin_rada','Value',mat2str(2))
                
            end

            if selectedButton == app.RucniinversekinematicsButton
                app.BrzinarobotaSlider.Enable = true;
                app.PosXEditField.Enable = true;
                app.PosYEditField.Enable = true;
                app.PosZEditField.Enable = true;
                app.Zglob1Slider.Enable = false;
                app.Zglob2Slider.Enable = false;
                app.Zglob3Slider.Enable = false;
                set_param('simulation_three_motors/nacin_rada','Value',mat2str(3))
                
            end


            if selectedButton == app.AutomatskiButton
                app.BrzinarobotaSlider.Enable = false;
                app.PosXEditField.Enable = false;
                app.PosYEditField.Enable = false;
                app.PosZEditField.Enable = false;
                app.Zglob1Slider.Enable = false;
                app.Zglob2Slider.Enable = false;
                app.Zglob3Slider.Enable = false;
                set_param('simulation_three_motors/nacin_rada','Value',mat2str(4))
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NainradaButtonGroup = uibuttongroup(app.UIFigure);
            app.NainradaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NainradaButtonGroupSelectionChanged, true);
            app.NainradaButtonGroup.Title = 'Način rada ';
            app.IskljucenoButton = uiradiobutton(app.NainradaButtonGroup);
            app.IskljucenoButton.Text = 'Iskljuceno';
            app.IskljucenoButton.Value = true;
            app.RucnidirectkinematicsButton = uiradiobutton(app.NainradaButtonGroup);
            app.RucnidirectkinematicsButton.Text = 'Rucni - direct kinematics';
            app.RucniinversekinematicsButton = uiradiobutton(app.NainradaButtonGroup);
            app.RucniinversekinematicsButton.Text = 'Rucni - inverse kinematics';
            app.AutomatskiButton = uiradiobutton(app.NainradaButtonGroup);
            app.AutomatskiButton.Text = 'Automatski';
            app.Zglob1SliderLabel = uilabel(app.UIFigure);
            app.Zglob1SliderLabel.Text = 'Zglob1';
            app.Zglob1Slider = uislider(app.UIFigure);
            app.Zglob1Slider.Limits = [0 360];
            app.Zglob1Slider.ValueChangedFcn = createCallbackFcn(app, @Join1SliderValueChanged, true);
            app.Zglob1Slider.ValueChangingFcn = createCallbackFcn(app, @Join1SliderValueChanged, true);
            app.Zglob2SliderLabel = uilabel(app.UIFigure);
            app.Zglob2SliderLabel.Text = 'Zglob2';
            app.Zglob2Slider = uislider(app.UIFigure);
            app.Zglob2Slider.ValueChangedFcn = createCallbackFcn(app, @Zglob2SliderValueChanged, true);
            app.Zglob3SliderLabel = uilabel(app.UIFigure);
            app.Zglob3SliderLabel.Text = 'Zglob3';
            app.Zglob3Slider = uislider(app.UIFigure);
            app.Zglob3Slider.Limits = [20 160];
            app.Zglob3Slider.ValueChangedFcn = createCallbackFcn(app, @Zglob3SliderValueChanged, true);
            app.Zglob3Slider.Value = 20;
            app.BrzinarobotaSliderLabel = uilabel(app.UIFigure);
            app.BrzinarobotaSliderLabel.Text = 'Brzina robota';
            app.BrzinarobotaSlider = uislider(app.UIFigure);
            app.BrzinarobotaSlider.Limits = [0.3 10];
            app.BrzinarobotaSlider.ValueChangedFcn = createCallbackFcn(app, @BrzinarobotaSliderValueChanged, true);
            app.BrzinarobotaSlider.Value = 0.5;
            app.PosXEditFieldLabel = uilabel(app.UIFigure);
            app.PosXEditFieldLabel.Text = 'PosX';
            app.PosXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosXEditField.ValueChangedFcn = createCallbackFcn(app, @PosXEditFieldValueChanged, true);
            app.PosYEditFieldLabel = uilabel(app.UIFigure);
            app.PosYEditFieldLabel.Text = 'PosY';
            app.PosYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosYEditField.ValueChangedFcn = createCallbackFcn(app, @PosYEditFieldValueChanged, true);
            app.PosZEditFieldLabel = uilabel(app.UIFigure);
            app.PosZEditFieldLabel.Text = 'PosZ';
            app.PosZEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosZEditField.ValueChangedFcn = createCallbackFcn(app, @PosZEditFieldValueChanged, true);
            app.DirectkinematicsLabel = uilabel(app.UIFigure);
            app.DirectkinematicsLabel.Text = 'Direct kinematics';
            app.InversekinematicsLabel = uilabel(app.UIFigure);
            app.InversekinematicsLabel.Text = 'Inverse kinematics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end